classdef FibreSimulationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OpticalFibreSimulationUIFigure  matlab.ui.Figure
        FinalLabel                      matlab.ui.control.Label
        PhaseangleLabel_val_final       matlab.ui.control.Label
        FrequencyshiftLabel_val_final   matlab.ui.control.Label
        ChirpLabel_val_final            matlab.ui.control.Label
        FWHMLabel_val_final             matlab.ui.control.Label
        PulsepositionLabel_val_final    matlab.ui.control.Label
        PeakamplitudeLabel_val_final    matlab.ui.control.Label
        InitialLabel                    matlab.ui.control.Label
        PulsetypeDropDown               matlab.ui.control.DropDown
        PulsetypeDropDownLabel          matlab.ui.control.Label
        PhaseangleLabel_val_initial     matlab.ui.control.Label
        PhaseangleLabel                 matlab.ui.control.Label
        radLabel_2                      matlab.ui.control.Label
        FrequencyshiftLabel_val_initial  matlab.ui.control.Label
        FrequencyshiftLabel             matlab.ui.control.Label
        radpsLabel_2                    matlab.ui.control.Label
        ChirpLabel_val_initial          matlab.ui.control.Label
        ChirpLabel                      matlab.ui.control.Label
        radps2Label_2                   matlab.ui.control.Label
        FWHMLabel_val_initial           matlab.ui.control.Label
        FWHMLabel                       matlab.ui.control.Label
        psLabel_4                       matlab.ui.control.Label
        PulsepositionLabel_val_initial  matlab.ui.control.Label
        PulsepositionLabel              matlab.ui.control.Label
        psLabel_3                       matlab.ui.control.Label
        PeakamplitudeLabel_val_initial  matlab.ui.control.Label
        PeakamplitudeLabel              matlab.ui.control.Label
        leftsqrtWrightLabel             matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ExitButton                      matlab.ui.control.Button
        ReportButton                    matlab.ui.control.Button
        SimulateButton                  matlab.ui.control.Button
        DropDown_AUnit                  matlab.ui.control.DropDown
        EffectivecoreareaAEditField     matlab.ui.control.NumericEditField
        EffectivecoreareaAEditFieldLabel  matlab.ui.control.Label
        DropDown_NUnit                  matlab.ui.control.DropDown
        Nonlinearcoefficientn2EditField  matlab.ui.control.NumericEditField
        Nonlinearcoefficientn2EditFieldLabel  matlab.ui.control.Label
        DropDown_DUnit                  matlab.ui.control.DropDown
        DispersioncoefficientDEditField  matlab.ui.control.NumericEditField
        DispersioncoefficientDEditFieldLabel  matlab.ui.control.Label
        DropDown_LossUnit               matlab.ui.control.DropDown
        LossEditField                   matlab.ui.control.NumericEditField
        LossEditFieldLabel              matlab.ui.control.Label
        DropDown_FibreLengthUnit        matlab.ui.control.DropDown
        FibrelengthLEditField           matlab.ui.control.NumericEditField
        FibrelengthLEditFieldLabel      matlab.ui.control.Label
        DropDown_WavelengthUnit         matlab.ui.control.DropDown
        WavelengthEditField             matlab.ui.control.NumericEditField
        WavelengthEditFieldLabel        matlab.ui.control.Label
        PeriodTEditField                matlab.ui.control.NumericEditField
        PeriodTEditFieldLabel           matlab.ui.control.Label
        DutycycleEditField              matlab.ui.control.NumericEditField
        DutycycleEditFieldLabel         matlab.ui.control.Label
        DropDownPeriodUnit              matlab.ui.control.DropDown
        OutputsLabel                    matlab.ui.control.Label
        InputsLabel                     matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            disp("run simulation")

            %disable simulation button to avoid clicking while calculating
            app.SimulateButton.Enable = "off";

            %% read all inputs
            % get period value and check if field is empty
            T_val = app.PeriodTEditField.Value;
            if isempty(T_val)
                % Display an error message
                errordlg('Period field cannot be empty.', 'Input Error', 'modal');
                return; % Exit the function
            end
            T_unit = app.DropDownPeriodUnit.Value; 

            % get duty cycle value and check if field is empty
            duty_cycle_val = app.DutycycleEditField.Value;
            if isempty(duty_cycle_val)
                % Display an error message
                errordlg('Duty cycle field cannot be empty.', 'Input Error', 'modal');
                return;
            end

            %get wavelength value and check if field is empty 
            Wavelength = app.WavelengthEditField.Value;
            if isempty(Wavelength)
                % Display an error message
                errordlg('Wavelength field cannot be empty.', 'Input Error', 'modal');
                return;
            end
            Wavelength_unit = app.DropDown_WavelengthUnit.Value; 

            % get Fibrelength value and check if field is empty
            Fibrelength = app.FibrelengthLEditField.Value;
            if isempty(Fibrelength)
                % Display an error message
                errordlg('Fibrelength field cannot be empty.', 'Input Error', 'modal');
                return;
            end
            Fibrelength_unit = app.DropDown_FibreLengthUnit.Value; 

            % get loss value and check if field is empty
            loss = app.LossEditField.Value;
            if isempty(loss)
                % Display an error message
                errordlg('Loss field cannot be empty.', 'Input Error', 'modal');
                return;
            end
            loss_unit = app.DropDown_LossUnit.Value; 

            % get dispersion value and check fi field is empty
            dispersion = app.DispersioncoefficientDEditField.Value;
            if isempty(dispersion)
                % Display an error message
                errordlg('Dispersion coefficient field cannot be empty.', 'Input Error', 'modal');
                return;
            end
            dispersion_unit = app.DropDown_DUnit.Value;

            %get non linear value and check if field is empty
            non_linear = app.Nonlinearcoefficientn2EditField.Value;
            if isempty(non_linear)
                % Display an error message
                errordlg('Nonlinear coefficient field cannot be empty.', 'Input Error', 'modal');
                return;
            end
            non_linear_unit = app.DropDown_NUnit.Value;  

            %get core area value and check if field is empty
            area = app.EffectivecoreareaAEditField.Value;
            if isempty(area)
                % Display an error message
                errordlg('Effective core area field cannot be empty.', 'Input Error', 'modal');
                return;
            end
            area_unit = app.DropDown_AUnit.Value; 
           
            %get the pulse type
            pulse_type = app.PulsetypeDropDown.Value; 

            %% print inputs for testing
            disp(['T: ' num2str(T_val) ' T_unit:' T_unit]);
            disp(['Duty cycle: ' num2str(duty_cycle_val)]);
            disp(['Wavelength: ' num2str(Wavelength) ' Unit: ' Wavelength_unit]);
            disp(['Firbre length: ' num2str(Fibrelength) ' Unit: ' Fibrelength_unit]);
            disp(['loss: ' num2str(loss) ' Unit: ' loss_unit]);
            disp(['dispersion: ' num2str(dispersion) ' Unit: ' dispersion_unit]);
            disp(['non_linear: ' num2str(non_linear) ' Unit: ' non_linear_unit]);
            disp(['area: ' num2str(area) ' Unit: ' area_unit]);
            disp(['Pulse type: ' pulse_type]);

            %% Convert all units to SI units 
            %if period is not in terms of s, convert
            T = T_val; 
            switch T_unit
                case "ms"
                    T = T*10^(-3); 
                case "μs"
                    T = T*10^(-6);
                case "ns"
                    T = T*10^(-9);
                case "ps"
                    T = T*10^(-12);
            end

            %if wavelength is not in terms of m, convert 
            lamda0 = Wavelength; 
            switch Wavelength_unit
                case "mm"
                    lamda0 = lamda0*10^(-3); 
                case "μm"
                    lamda0 = lamda0*10^(-6);
                case "nm"
                    lamda0 = lamda0*10^(-9);
            end

            %if fibre length is not in terms of m, convert 
            L = Fibrelength; 
            if Fibrelength_unit == "km"
                L  = L*10^3;
            end

            %if loss is not in terms of dB/m, convert
            alphadB = loss; 
            if loss_unit == "dB/km"
                alphadB = alphadB*10^(-3); 
            end

            %if dispersion not in s/m^2
            D = dispersion; 
            if dispersion_unit == "ps/nm/km"
                D = D*10^(-6); 
            end

            %if non linear coefficient not in terms of  m^2/W, convert 
            n2 = non_linear; 
            if non_linear_unit == "km²/W"
                n2 = n2*10^(-20); 
            end
            
            %if area not in terms of m², convert
            Aeff = area; 
            if area_unit == "μm²" 
                Aeff = Aeff*10^(-12);
            elseif area_unit == "mm²"
                Aeff = Aeff*10^(-6); 
            end
            

            %% print values as SI unit for testing
            disp(['period: ' num2str(T) ' s']);
            disp(['wavelength: ' num2str(lamda0) ' m']);
            disp(['Fibre length: ' num2str(L) ' m']);
            disp(['loss: ' num2str(alphadB) ' dB/m']);
            disp(['dispersion: ' num2str(D) ' s/m^2']);
            disp(['non linear coefficient: ' num2str(n2) ' m²/W']);
            disp(['area: ' num2str(Aeff) ' m²']);
            
            %% Perform Simulation Calculations 
            disp("calculating ...");
            %setup the simulation class
            Simulation = SimulationCalculator(T, duty_cycle_val, lamda0, L, alphadB, D, n2, Aeff); 
            %get the result of the simulation 
            [psi_evoluation, t, omega, dt, z, Nz] = Simulation.performSimulation(pulse_type); 

            %get t in ps 
            t_ps = t/(10^(-12));
            %get dt in ps
            dt_ps = dt*(10^12);
            %get omega in ps
            omega_rad_ps = omega * 10^(-12);
            
            disp("finished");

            %% Calculate Initial and final parameters 
            %select initial and final pulse
            psi_initial = psi_evoluation(1, :); 
            psi_final = psi_evoluation(end, :); 

            %calculate inital and final peak amplitude 
            peak_amplitude_initial = PulseParameters.calculatePeakAmplitude(psi_initial); 
            peak_amplitude_final = PulseParameters.calculatePeakAmplitude(psi_final);

            %calculate inital and final temporal pulse position 
            temporal_pulse_position_initial = PulseParameters.calculateTemporalPulsePosition(psi_initial, t_ps);
            temporal_pulse_position_final = PulseParameters.calculateTemporalPulsePosition(psi_final, t_ps);

            %calculate the inital and final FWHM 
            FWHM_initial = PulseParameters.calculateFWHM(psi_initial, t_ps);
            FWHM_final = PulseParameters.calculateFWHM(psi_final, t_ps); 

            %chirp 
            chirp_inital = PulseParameters.calculateChirp(psi_initial, t_ps, omega_rad_ps, dt_ps); 
            chirp_final = PulseParameters.calculateChirp(psi_final, t_ps, omega_rad_ps, dt_ps); 

            %frequency shift 
            freq_shift_initial = PulseParameters.calculateFrequencyShift(psi_initial, omega); 
            freq_shift_initial = freq_shift_initial* 10^(-12); % Get it in ps
            freq_shift_final = PulseParameters.calculateFrequencyShift(psi_final, omega); 
            freq_shift_final = freq_shift_final* 10^(-12); % Get it in ps

            %phase angle 
            phase_angle_intial = PulseParameters.calculatePhaseAngle(psi_initial); 
            phase_angle_final = PulseParameters.calculatePhaseAngle(psi_final); 
            

            %% Display Parameters 
            app.PeakamplitudeLabel_val_initial.Text = PulseParameters.formatValue(peak_amplitude_initial);
            app.PeakamplitudeLabel_val_final.Text = PulseParameters.formatValue(peak_amplitude_final);
            app.PulsepositionLabel_val_initial.Text = PulseParameters.formatValue(temporal_pulse_position_initial); 
            app.PulsepositionLabel_val_final.Text = PulseParameters.formatValue(temporal_pulse_position_final); 
            app.FWHMLabel_val_initial.Text = PulseParameters.formatValue(FWHM_initial); 
            app.FWHMLabel_val_final.Text = PulseParameters.formatValue(FWHM_final); 
            app.ChirpLabel_val_initial.Text = PulseParameters.formatValue(chirp_inital); 
            app.ChirpLabel_val_final.Text = PulseParameters.formatValue(chirp_final); 
            app.FrequencyshiftLabel_val_initial.Text = PulseParameters.formatValue(freq_shift_initial); 
            app.FrequencyshiftLabel_val_final.Text = PulseParameters.formatValue(freq_shift_final);
            app.PhaseangleLabel_val_initial.Text = PulseParameters.formatValue(phase_angle_intial); 
            app.PhaseangleLabel_val_final.Text = PulseParameters.formatValue(phase_angle_final); 


            %% Create and display the plot
            %Create 3d plot for only a subset of z points to show propagation
            selected_z_indices = round(linspace(1, Nz, 20)); %select 20 points for plotting
            %selected the relevant values 
            selected_psi_vals = psi_evoluation(selected_z_indices, :); 
            selected_z_values = z(selected_z_indices); 
            %convert z to km instead of meters 
            selected_z_values_km = selected_z_values/1000;
            %calculate intensities 
            selected_intensities = abs(selected_psi_vals).^2;

            %clear the UIAxes
            cla(app.UIAxes);
            %plot on the UIAxis 
            hold(app.UIAxes, "on"); 
            for i = 1:numel(selected_z_indices)
                %plot the signal for the corresponding z value
                 plot3(app.UIAxes, t_ps, selected_z_values_km(i)*ones(size(t_ps)), selected_intensities(i, :), 'Color', 'blue', 'LineWidth', 1);
            end
            %add grid to plot
            grid(app.UIAxes, 'on');

            %add labels
            xlabel(app.UIAxes, 't [ps]');
            ylabel(app.UIAxes, 'z [km]');
            zlabel(app.UIAxes, '|\psi(z = 0, t)|^2 [W]');

            %adjust rotation
            view(app.UIAxes, 45, 30)

            %adjust axis
            ylim(app.UIAxes, [0, selected_z_values_km(end)]);
            xticks(app.UIAxes, min(t_ps):100:max(t_ps));

            %add title
            title(app.UIAxes, "Signal Propagation Through Optical Fibre");

            %% Enable buttons and display plot
            app.SimulateButton.Enable = "on"; %turn simulation button back on
            % After calculations, enable the ReportButton
            app.ReportButton.Enable = 'on';
            %display the plot 
            app.UIAxes.Visible = 'on';
        end

        % Button pushed function: ReportButton
        function ReportButtonPushed(app, event)
            disp("Creating report"); 

            %{
            1. Load all the values 
            2. createa 1 long string that becomes the latex file 
            3. save latext file (check if it renders on overleaf
            %}
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            %close the app on exit button click
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OpticalFibreSimulationUIFigure = uifigure('Visible', 'off');
            app.OpticalFibreSimulationUIFigure.Name = 'OpticalFibreSimulation';
            app.UIAxes = uiaxes(app.OpticalFibreSimulationUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.InputsLabel = uilabel(app.OpticalFibreSimulationUIFigure);
            app.InputsLabel.Text = 'Inputs ';
            app.OutputsLabel = uilabel(app.OpticalFibreSimulationUIFigure);
            app.OutputsLabel.Text = 'Outputs';
            app.DropDownPeriodUnit = uidropdown(app.OpticalFibreSimulationUIFigure);
            app.DropDownPeriodUnit.Items = {'s', 'ms', 'μs', 'ns', 'ps'};
            app.DropDownPeriodUnit.Value = 'ps';
            app.DutycycleEditFieldLabel = uilabel(app.OpticalFibreSimulationUIFigure);
            app.DutycycleEditFieldLabel.Text = 'Duty cycle:';
            app.DutycycleEditField = uieditfield(app.OpticalFibreSimulationUIFigure, 'numeric');
            app.DutycycleEditField.Limits = [0 100];
            app.DutycycleEditField.AllowEmpty = 'on';
            app.DutycycleEditField.Value = 25;
            app.PeriodTEditFieldLabel = uilabel(app.OpticalFibreSimulationUIFigure);
            app.PeriodTEditFieldLabel.Text = 'Period (T): ';
            app.PeriodTEditField = uieditfield(app.OpticalFibreSimulationUIFigure, 'numeric');
            app.PeriodTEditField.Limits = [0 Inf];
            app.PeriodTEditField.AllowEmpty = 'on';
            app.PeriodTEditField.Value = 100;
            app.WavelengthEditFieldLabel = uilabel(app.OpticalFibreSimulationUIFigure);
            app.WavelengthEditFieldLabel.Text = 'Wavelength:';
            app.WavelengthEditField = uieditfield(app.OpticalFibreSimulationUIFigure, 'numeric');
            app.WavelengthEditField.Limits = [0 Inf];
            app.WavelengthEditField.AllowEmpty = 'on';
            app.WavelengthEditField.Value = 1.55;
            app.DropDown_WavelengthUnit = uidropdown(app.OpticalFibreSimulationUIFigure);
            app.DropDown_WavelengthUnit.Items = {'m', 'mm', 'μm', 'nm'};
            app.DropDown_WavelengthUnit.Value = 'μm';
            app.FibrelengthLEditFieldLabel = uilabel(app.OpticalFibreSimulationUIFigure);
            app.FibrelengthLEditFieldLabel.Text = 'Fibre length (L):';
            app.FibrelengthLEditField = uieditfield(app.OpticalFibreSimulationUIFigure, 'numeric');
            app.FibrelengthLEditField.Limits = [0 Inf];
            app.FibrelengthLEditField.ValueDisplayFormat = '%11.5g';
            app.FibrelengthLEditField.AllowEmpty = 'on';
            app.FibrelengthLEditField.Value = 28.845;
            app.DropDown_FibreLengthUnit = uidropdown(app.OpticalFibreSimulationUIFigure);
            app.DropDown_FibreLengthUnit.Items = {'m', 'km'};
            app.DropDown_FibreLengthUnit.Value = 'km';
            app.LossEditFieldLabel = uilabel(app.OpticalFibreSimulationUIFigure);
            app.LossEditFieldLabel.Text = 'Loss: ';
            app.LossEditField = uieditfield(app.OpticalFibreSimulationUIFigure, 'numeric');
            app.LossEditField.Limits = [0 Inf];
            app.LossEditField.AllowEmpty = 'on';
            app.LossEditField.Value = 0.2;
            app.DropDown_LossUnit = uidropdown(app.OpticalFibreSimulationUIFigure);
            app.DropDown_LossUnit.Items = {'dB/km', 'dB/m'};
            app.DropDown_LossUnit.Value = 'dB/km';
            app.DispersioncoefficientDEditFieldLabel = uilabel(app.OpticalFibreSimulationUIFigure);
            app.DispersioncoefficientDEditFieldLabel.Text = 'Dispersion coefficient (D):';
            app.DispersioncoefficientDEditField = uieditfield(app.OpticalFibreSimulationUIFigure, 'numeric');
            app.DispersioncoefficientDEditField.Limits = [0 Inf];
            app.DispersioncoefficientDEditField.AllowEmpty = 'on';
            app.DispersioncoefficientDEditField.Value = 17;
            app.DropDown_DUnit = uidropdown(app.OpticalFibreSimulationUIFigure);
            app.DropDown_DUnit.Items = {'ps/nm/km', 's/m²'};
            app.DropDown_DUnit.Value = 'ps/nm/km';
            app.Nonlinearcoefficientn2EditFieldLabel = uilabel(app.OpticalFibreSimulationUIFigure);
            app.Nonlinearcoefficientn2EditFieldLabel.Text = 'Non linear coefficient (n2):';
            app.Nonlinearcoefficientn2EditField = uieditfield(app.OpticalFibreSimulationUIFigure, 'numeric');
            app.Nonlinearcoefficientn2EditField.Limits = [0 Inf];
            app.Nonlinearcoefficientn2EditField.AllowEmpty = 'on';
            app.Nonlinearcoefficientn2EditField.Value = 2.7;
            app.DropDown_NUnit = uidropdown(app.OpticalFibreSimulationUIFigure);
            app.DropDown_NUnit.Items = {'km²/W', 'm²/W'};
            app.DropDown_NUnit.Value = 'km²/W';
            app.EffectivecoreareaAEditFieldLabel = uilabel(app.OpticalFibreSimulationUIFigure);
            app.EffectivecoreareaAEditFieldLabel.Text = 'Effective core area (A):';
            app.EffectivecoreareaAEditField = uieditfield(app.OpticalFibreSimulationUIFigure, 'numeric');
            app.EffectivecoreareaAEditField.Limits = [0 Inf];
            app.EffectivecoreareaAEditField.AllowEmpty = 'on';
            app.EffectivecoreareaAEditField.Value = 55;
            app.DropDown_AUnit = uidropdown(app.OpticalFibreSimulationUIFigure);
            app.DropDown_AUnit.Items = {'m²', 'mm²', 'μm²'};
            app.DropDown_AUnit.Value = 'μm²';
            app.SimulateButton = uibutton(app.OpticalFibreSimulationUIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.ReportButton = uibutton(app.OpticalFibreSimulationUIFigure, 'push');
            app.ReportButton.ButtonPushedFcn = createCallbackFcn(app, @ReportButtonPushed, true);
            app.ReportButton.Enable = 'off';
            app.ReportButton.Text = 'Report';
            app.ExitButton = uibutton(app.OpticalFibreSimulationUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.Label = uilabel(app.OpticalFibreSimulationUIFigure);
            app.Label.Text = '%';
            app.leftsqrtWrightLabel = uilabel(app.OpticalFibreSimulationUIFigure);
            app.leftsqrtWrightLabel.Interpreter = 'latex';
            app.leftsqrtWrightLabel.Text = '$\left[\sqrt{W}\right]$';
            app.PeakamplitudeLabel = uilabel(app.OpticalFibreSimulationUIFigure);
            app.PeakamplitudeLabel.Text = 'Peak amplitude:';
            app.PeakamplitudeLabel_val_initial = uilabel(app.OpticalFibreSimulationUIFigure);
            app.PeakamplitudeLabel_val_initial.Text = '';
            app.psLabel_3 = uilabel(app.OpticalFibreSimulationUIFigure);
            app.psLabel_3.Interpreter = 'latex';
            app.psLabel_3.Text = 'ps';
            app.PulsepositionLabel = uilabel(app.OpticalFibreSimulationUIFigure);
            app.PulsepositionLabel.Text = 'Pulse position:';
            app.PulsepositionLabel_val_initial = uilabel(app.OpticalFibreSimulationUIFigure);
            app.PulsepositionLabel_val_initial.Text = '';
            app.psLabel_4 = uilabel(app.OpticalFibreSimulationUIFigure);
            app.psLabel_4.Interpreter = 'latex';
            app.psLabel_4.Text = 'ps';
            app.FWHMLabel = uilabel(app.OpticalFibreSimulationUIFigure);
            app.FWHMLabel.Text = 'FWHM:';
            app.FWHMLabel_val_initial = uilabel(app.OpticalFibreSimulationUIFigure);
            app.FWHMLabel_val_initial.Text = '';
            app.radps2Label_2 = uilabel(app.OpticalFibreSimulationUIFigure);
            app.radps2Label_2.Interpreter = 'latex';
            app.radps2Label_2.Text = 'rad/ps$^2$';
            app.ChirpLabel = uilabel(app.OpticalFibreSimulationUIFigure);
            app.ChirpLabel.Text = 'Chirp:';
            app.ChirpLabel_val_initial = uilabel(app.OpticalFibreSimulationUIFigure);
            app.ChirpLabel_val_initial.Text = '';
            app.radpsLabel_2 = uilabel(app.OpticalFibreSimulationUIFigure);
            app.radpsLabel_2.Interpreter = 'latex';
            app.radpsLabel_2.Text = 'rad/ps';
            app.FrequencyshiftLabel = uilabel(app.OpticalFibreSimulationUIFigure);
            app.FrequencyshiftLabel.Text = 'Frequency shift:';
            app.FrequencyshiftLabel_val_initial = uilabel(app.OpticalFibreSimulationUIFigure);
            app.FrequencyshiftLabel_val_initial.Text = '';
            app.radLabel_2 = uilabel(app.OpticalFibreSimulationUIFigure);
            app.radLabel_2.Interpreter = 'latex';
            app.radLabel_2.Text = 'rad';
            app.PhaseangleLabel = uilabel(app.OpticalFibreSimulationUIFigure);
            app.PhaseangleLabel.Text = 'Phase angle:';
            app.PhaseangleLabel_val_initial = uilabel(app.OpticalFibreSimulationUIFigure);
            app.PhaseangleLabel_val_initial.Text = '';
            app.PulsetypeDropDownLabel = uilabel(app.OpticalFibreSimulationUIFigure);
            app.PulsetypeDropDownLabel.Text = 'Pulse type:';
            app.PulsetypeDropDown = uidropdown(app.OpticalFibreSimulationUIFigure);
            app.PulsetypeDropDown.Items = {'Soliton', 'Gaussian'};
            app.PulsetypeDropDown.Value = 'Soliton';
            app.InitialLabel = uilabel(app.OpticalFibreSimulationUIFigure);
            app.InitialLabel.Text = 'Initial';
            app.PeakamplitudeLabel_val_final = uilabel(app.OpticalFibreSimulationUIFigure);
            app.PeakamplitudeLabel_val_final.Text = '';
            app.PulsepositionLabel_val_final = uilabel(app.OpticalFibreSimulationUIFigure);
            app.PulsepositionLabel_val_final.Text = '';
            app.FWHMLabel_val_final = uilabel(app.OpticalFibreSimulationUIFigure);
            app.FWHMLabel_val_final.Text = '';
            app.ChirpLabel_val_final = uilabel(app.OpticalFibreSimulationUIFigure);
            app.ChirpLabel_val_final.Text = '';
            app.FrequencyshiftLabel_val_final = uilabel(app.OpticalFibreSimulationUIFigure);
            app.FrequencyshiftLabel_val_final.Text = '';
            app.PhaseangleLabel_val_final = uilabel(app.OpticalFibreSimulationUIFigure);
            app.PhaseangleLabel_val_final.Text = '';
            app.FinalLabel = uilabel(app.OpticalFibreSimulationUIFigure);
            app.FinalLabel.Text = 'Final';
            % Show the figure after all components are created
            app.OpticalFibreSimulationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FibreSimulationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OpticalFibreSimulationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OpticalFibreSimulationUIFigure)
        end
    end
end